classdef Ion_Gabriel_424A_Aplicatie_Coin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        ValueButton           matlab.ui.control.Button
        ShowCoinsButton       matlab.ui.control.Button
        CoinRecognitionLabel  matlab.ui.control.Label
        UploadButton          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cauta_doar_monede % poza cu monedele masca
        props           %ariile fiecarei monede
        cea_mare %cea mai mare moneda
        m5
        m10
        m50
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function AddUploadButtonPushedFcn(app, event)
            
            %[filename, pathname] = uigetfile({'*.png;*.jpg'});         % CITIM PNG UL CU BANUTI
            
            A = imread(uigetfile({'*.png;*.jpg'}));
            drawnow;
            figure(app.UIFigure)           %SOLUTIE DE COMPROMIS CA SA NU MAI INTRE IN BARA
            
            
            A = im2gray(A);
            B = imbinarize(A);                  %BINARIZARE
            
            C = ~B;
            C = imfill(C, "holes");             %FACEM MASCA, UMPLEM GAURILE
            
            app.cauta_doar_monede = bwpropfilt(C, "Area", [1000 900000]);
            app.cauta_doar_monede = bwlabel(app.cauta_doar_monede);
            %figure; imshow(ez);     
            numar = bwconncomp(app.cauta_doar_monede);


            app.props = regionprops(app.cauta_doar_monede, 'Area');


            %[~, index] = max([app.props.Area]);
            
            %largest_region = false(size(app.cauta_doar_monede));

            %largest_region(app.cauta_doar_monede == index) = 1;
            
            [app.cea_mare, index] = max([app.props.Area]);
            
            fieldValues = cell2mat(struct2cell(app.props));

            app.m5=0;
            app.m10=0;
            app.m50=0;

            for i = 1:length(fieldValues)
                  if(fieldValues(i)/app.cea_mare < 0.65)        
                      app.m5 = app.m5+1
                  elseif(fieldValues(i)/app.cea_mare < 0.91)
                        app.m10 = app.m10 + 1;
                  elseif(fieldValues(i)/app.cea_mare < 1.01)
                        app.m50 = app.m50 + 1;
                  end
            end


            %imshow(cauta_doar_monede, "Parent", app.UIAxes);


        end

        % Callback function: ShowCoinsButton, UIAxes
        function ShowCoinsFcn(app, event)
           %app.UIAxes.Position = [100, 100, 400, 400]


            imshow(app.cauta_doar_monede, "Parent", app.UIAxes);
            imshow(app.cauta_doar_monede)
            hold(app.UIAxes, 'on');

            for i = 1:numel(app.props)
                [y, x] = find(app.cauta_doar_monede == i); 
                centroid = [mean(x), mean(y)]; 
    
                areaValue = app.props(i).Area;
    
                    
                text(app.UIAxes, centroid(1), centroid(2), sprintf('%.2f', areaValue/1000), 'Color', 'r', 'FontSize', 10);
                text(centroid(1), centroid(2), sprintf('%.2f', areaValue/1000), 'Color', 'r', 'FontSize', 10);
            end
               

        end

        % Button pushed function: ValueButton
        function CalculareValoareFcn(app, event)


total = 0.05*app.m5 + 0.1*app.m10 + 0.5*app.m50;


formattedText = sprintf('Monede 5 bani: %d\nMonede 10 bani: %d\nMonede 50 bani: %d\nSUMA TOTALA ESTE: %.2f RON', app.m5, app.m10, app.m50, total);
app.TextArea.Value = formattedText;
app.UIAxes.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Coin MASK')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ShowCoinsFcn, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @AddUploadButtonPushedFcn, true);
            app.UploadButton.Text = 'Upload ';
            app.CoinRecognitionLabel = uilabel(app.UIFigure);
            app.CoinRecognitionLabel.Text = 'Coin Recognition';
            app.ShowCoinsButton = uibutton(app.UIFigure, 'push');
            app.ShowCoinsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCoinsFcn, true);
            app.ShowCoinsButton.Text = 'Show Coins';
            app.ValueButton = uibutton(app.UIFigure, 'push');
            app.ValueButton.ButtonPushedFcn = createCallbackFcn(app, @CalculareValoareFcn, true);
            app.ValueButton.Text = 'Value ';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ion_Gabriel_424A_Aplicatie_Coin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end